classdef picco_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        DrawButton                matlab.ui.control.Button
        InputsourceDropDown       matlab.ui.control.DropDown
        InputsourceDropDownLabel  matlab.ui.control.Label
        EndrowEditField           matlab.ui.control.NumericEditField
        EndrowEditFieldLabel      matlab.ui.control.Label
        StartrowEditField         matlab.ui.control.NumericEditField
        StartrowEditFieldLabel    matlab.ui.control.Label
        Tree                      matlab.ui.container.CheckBoxTree
        PiccovaluesNode           matlab.ui.container.TreeNode
        PCCONode                  matlab.ui.container.TreeNode
        HRNode                    matlab.ui.container.TreeNode
        APsNode                   matlab.ui.container.TreeNode
        APdNode                   matlab.ui.container.TreeNode
        APmNode                   matlab.ui.container.TreeNode
        CVPNode                   matlab.ui.container.TreeNode
        SVNode                    matlab.ui.container.TreeNode
        SVRNode                   matlab.ui.container.TreeNode
        DPMXNode                  matlab.ui.container.TreeNode
        CONode                    matlab.ui.container.TreeNode
        SVVNode                   matlab.ui.container.TreeNode
        PPVNode                   matlab.ui.container.TreeNode
        PCCINode                  matlab.ui.container.TreeNode
        SVINode                   matlab.ui.container.TreeNode
        SVRINode                  matlab.ui.container.TreeNode
        SVMinNode                 matlab.ui.container.TreeNode
        SVMaxNode                 matlab.ui.container.TreeNode
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        piccoTable % Tablazat, ahol a beolvasott ertekeket tartjuk
        startRowIndex % startRowIndex
        endRowIndex % endRowIndex
        error % error
        selectedPiccoValues % selectedPiccoValues
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.piccoTable = readtable('resources/data/picco_0.csv'); % xlsl fajl beolvasasa
            assignin('base', 'piccoTable', app.piccoTable)
            %             summary(app.piccoTable);
        end

        % Value changed function: InputsourceDropDown
        function InputsourceDropDownValueChanged(app, event)
            value = app.InputsourceDropDown.Value;
            disp(value)
            switch value
                case 'picco0'
                    app.piccoTable = readtable('resources/data/picco_0.csv'); % xlsl fajl beolvasasa
                case 'picco1'
                    app.piccoTable = readtable('resources/data/picco_1.csv'); % xlsl fajl beolvasasa
                case 'picco2'
                    app.piccoTable = readtable('resources/data/picco_2.csv'); % xlsl fajl beolvasasa
                case 'picco3'
                    app.piccoTable = readtable('resources/data/picco_3.csv'); % xlsl fajl beolvasasa
            end

        end

        % Value changed function: StartrowEditField
        function StartrowEditFieldValueChanged(app, event)
            piccoTableHeight = height(app.piccoTable)
            disp(piccoTableHeight)
            if(app.StartrowEditField.Value > piccoTableHeight)
                app.error = errordlg('Start row index cant be greater then piccoTableLength','Input Error');
            else
                app.startRowIndex = app.StartrowEditField.Value;
            end
        end

        % Value changed function: EndrowEditField
        function EndrowEditFieldValueChanged(app, event)
            piccoTableHeight = height(app.piccoTable);
            disp(piccoTableHeight)
            if(app.EndrowEditField.Value > piccoTableHeight)
                app.error = errordlg('End row index cant be greater then piccoTableLength','Input Error');
            elseif(app.EndrowEditField.Value < app.startRowIndex)
                app.error = errordlg('Start row index cant be greater then end row index','Input Error');
            else
                app.endRowIndex = app.EndrowEditField.Value;
            end

        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            for i = 1:length(app.selectedPiccoValues)
                disp(app.selectedPiccoValues(i).Text)
                disp(app.startRowIndex)
                disp(app.endRowIndex)
                disp(app.UIAxes.Title.String)
                switch app.selectedPiccoValues(i).Text
                    case 'PCCO'
                        disp('PCCO')
                        plot(app.UIAxes, app.piccoTable.PCCO(app.startRowIndex:app.endRowIndex,:));
                        title(app.UIAxes, strcat(app.UIAxes.Title.String,'& PCCO'))
                        hold(app.UIAxes,'on');
                    case 'HR'
                        plot(app.UIAxes, app.piccoTable.HR(app.startRowIndex:app.endRowIndex,:));
                        title(app.UIAxes, strcat(app.UIAxes.Title.String,'& HR'))
                        hold(app.UIAxes,'on');
                    case 'APs'
                        plot(app.UIAxes, app.piccoTable.APs(app.startRowIndex:app.endRowIndex,:));
                        title(app.UIAxes, strcat(app.UIAxes.Title.String,'& APs'))
                        hold(app.UIAxes,'on');
                    case 'APd'
                        plot(app.UIAxes, app.piccoTable.APd(app.startRowIndex:app.endRowIndex,:));
                        title(app.UIAxes, strcat(app.UIAxes.Title.String,'& APd'))
                        hold(app.UIAxes,'on');
                    case 'APm'
                        plot(app.UIAxes, app.piccoTable.APm(app.startRowIndex:app.endRowIndex,:));
                        title(app.UIAxes, strcat(app.UIAxes.Title.String,'& APm'))
                        hold(app.UIAxes,'on');
                    case 'CVP'
                        plot(app.UIAxes, app.piccoTable.CVP(app.startRowIndex:app.endRowIndex,:));
                        title(app.UIAxes, strcat(app.UIAxes.Title.String,'& CVP'))
                        hold(app.UIAxes,'on');
                end
            end
            hold(app.UIAxes,'off');
            %             plot(app.UIAxes, app.piccoTable.APs(1:20,:));
            %              app.UIAxes = uiaxes(app.RightPanel);
            %             title(app.UIAxes, 'Title')
            %             xlabel(app.UIAxes, 'X')
            %             ylabel(app.UIAxes, 'Y')
            %             zlabel(app.UIAxes, 'Z')
            %             app.UIAxes.Position = [3 282 643 243];
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            app.selectedPiccoValues = app.Tree.CheckedNodes;
            display(app.selectedPiccoValues);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {614, 614};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {300, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Tree = uitree(app.LeftPanel, 'checkbox');
            app.PiccovaluesNode = uitreenode(app.Tree);
            app.PiccovaluesNode.Text = 'Picco values';
            app.PCCONode = uitreenode(app.PiccovaluesNode);
            app.PCCONode.Text = 'PCCO';
            app.HRNode = uitreenode(app.PiccovaluesNode);
            app.HRNode.Text = 'HR';
            app.APsNode = uitreenode(app.PiccovaluesNode);
            app.APsNode.Text = 'APs';
            app.APdNode = uitreenode(app.PiccovaluesNode);
            app.APdNode.Text = 'APd';
            app.APmNode = uitreenode(app.PiccovaluesNode);
            app.APmNode.Text = 'APm';
            app.CVPNode = uitreenode(app.PiccovaluesNode);
            app.CVPNode.Text = 'CVP';
            app.SVNode = uitreenode(app.PiccovaluesNode);
            app.SVNode.Text = 'SV';
            app.SVRNode = uitreenode(app.PiccovaluesNode);
            app.SVRNode.Text = 'SVR';
            app.DPMXNode = uitreenode(app.PiccovaluesNode);
            app.DPMXNode.Text = 'DPMX';
            app.CONode = uitreenode(app.PiccovaluesNode);
            app.CONode.Text = 'CO';
            app.SVVNode = uitreenode(app.PiccovaluesNode);
            app.SVVNode.Text = 'SVV';
            app.PPVNode = uitreenode(app.PiccovaluesNode);
            app.PPVNode.Text = 'PPV';
            app.PCCINode = uitreenode(app.PiccovaluesNode);
            app.PCCINode.Text = 'PCCI';
            app.SVINode = uitreenode(app.PiccovaluesNode);
            app.SVINode.Text = 'SVI';
            app.SVRINode = uitreenode(app.PiccovaluesNode);
            app.SVRINode.Text = 'SVRI';
            app.SVMinNode = uitreenode(app.PiccovaluesNode);
            app.SVMinNode.Text = 'SVMin';
            app.SVMaxNode = uitreenode(app.PiccovaluesNode);
            app.SVMaxNode.Text = 'SVMax';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.StartrowEditFieldLabel = uilabel(app.LeftPanel);
            app.StartrowEditFieldLabel.Text = 'Start row';
            app.StartrowEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StartrowEditField.ValueChangedFcn = createCallbackFcn(app, @StartrowEditFieldValueChanged, true);
            app.EndrowEditFieldLabel = uilabel(app.LeftPanel);
            app.EndrowEditFieldLabel.Text = 'End row';
            app.EndrowEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EndrowEditField.ValueChangedFcn = createCallbackFcn(app, @EndrowEditFieldValueChanged, true);
            app.InputsourceDropDownLabel = uilabel(app.LeftPanel);
            app.InputsourceDropDownLabel.Text = 'Input source';
            app.InputsourceDropDown = uidropdown(app.LeftPanel);
            app.InputsourceDropDown.Items = {'picco0', 'picco1', 'picco2', 'picco3'};
            app.InputsourceDropDown.ValueChangedFcn = createCallbackFcn(app, @InputsourceDropDownValueChanged, true);
            app.InputsourceDropDown.Value = 'picco0';
            app.DrawButton = uibutton(app.LeftPanel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Picco: ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = picco_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end